classdef WhiskerAnnotator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        SelectDirectoryButton       matlab.ui.control.Button
        directoryLabel              matlab.ui.control.Label
        NumberofImagesLabel         matlab.ui.control.Label
        NumberOfImagesDisplay       matlab.ui.control.Label
        forwardButton               matlab.ui.control.Button
        backButton                  matlab.ui.control.Button
        drawButton                  matlab.ui.control.Button
        PointsSpinnerLabel          matlab.ui.control.Label
        PointsSpinner               matlab.ui.control.Spinner
        RadiusSpinnerLabel          matlab.ui.control.Label
        RadiusSpinner               matlab.ui.control.Spinner
        CurrentImageLabel           matlab.ui.control.Label
        CurrentImageDisplay         matlab.ui.control.Label
        MarkerPrefixEditFieldLabel  matlab.ui.control.Label
        MarkerPrefixEditField       matlab.ui.control.EditField
        saveButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        directory % Description
    end

    properties (Access = public)
        imgFiles % Description
        nImages % Description
        currentImage % Description
        resultsAll % Description
        nPoints % Description
        inDrawMode % Description
        circleRadius % Description
        markerPrefix % Description
    end

    methods (Access = private)
    
        function results = getImages(app)
            results = dir(fullfile(app.directory, '*.png'));
            results = {results(:).name};
            if length(results) > 0
                results = natsortfiles(results);
            else
                results = [];
            end
        end
        
        function results = updateDisplay(app)
            % parameters
            app.NumberOfImagesDisplay.Text = num2str(app.nImages);
            app.CurrentImageDisplay.Text = num2str(app.currentImage);
            
            % image
            img = imread(fullfile(app.directory, app.imgFiles{app.currentImage}));
            imshow(img, 'Parent', app.UIAxes);
            hold(app.UIAxes);
            if ~all(isnan(app.resultsAll(app.currentImage, :)))
                for p = 1:2:(app.nPoints*2)
                    scatter(app.resultsAll(app.currentImage, p), app.resultsAll(app.currentImage, p+1), 'r.', 'Parent', app.UIAxes);
                end
            end
            hold(app.UIAxes, 'off');
            app.UIAxes.Title.String = app.imgFiles{app.currentImage};
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.currentImage = 1;
            app.inDrawMode = false;
        end

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)
            app.directory = uigetdir;
            app.directoryLabel.Text = app.directory;
            app.imgFiles = getImages(app);
            app.nImages = length(app.imgFiles);
            app.nPoints = app.PointsSpinner.Value;
            app.circleRadius = app.RadiusSpinner.Value;
            app.resultsAll = nan(app.nImages, app.nPoints*2);
            
            app.updateDisplay();
        end

        % Button pushed function: forwardButton
        function forwardButtonPushed(app, event)
            if app.currentImage < app.nImages
                app.currentImage = app.currentImage + 1;
                app.updateDisplay();
            end
        end

        % Button pushed function: backButton
        function backButtonPushed(app, event)
            if app.currentImage > 1
               app.currentImage = app.currentImage - 1;
               app.updateDisplay();
            end
        end

        % Button pushed function: drawButton
        function drawButtonPushed(app, event)
            if ~app.inDrawMode
                app.inDrawMode = true;
                f = figure;
                h = imshow(imread(fullfile(app.directory, app.imgFiles{app.currentImage})));
                hold on;
                
                coords = [];
                for pointInd = 1:app.nPoints
                    disp(pointInd);
                    p = impoint();
                    
                    xy = p.getPosition(); x = xy(1); y = xy(2);
                    coords = [coords, x, y];
                    
                    th = 0:pi/50:2*pi;
                    xunit = app.circleRadius * cos(th) + x;
                    yunit = app.circleRadius * sin(th) + y;
                    
                    plot(xunit, yunit, 'r');
                    plot([0, x], [y, y], 'r');
                    
                end
                
                app.resultsAll(app.currentImage, :) = coords;
                app.inDrawMode = false;
                close(f);
            end
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.markerPrefix = app.MarkerPrefixEditField.Value;
            
            c = 1;
            header = {'', 'Area', 'Mean', 'Min', 'Max', 'X', 'Y', 'Slice'};
            commaHeader = [header; repmat({','}, 1, numel(header))];
            commaHeader = commaHeader(:)';
            textHeader = cell2mat(commaHeader);
            
            for n = 1:2:(app.nPoints*2)
                thisPart = zeros(app.nImages, 8);
                thisPart(:, 1) = 1:app.nImages;
                thisPart(:, 8) = 1:app.nImages;
                thisPart(:, 6:7) = app.resultsAll(:, n:(n+1));
                
                partNames = {};
                for pn = 1:app.nPoints
                   partNames{pn} = [app.markerPrefix, num2str(pn)]; 
                end
                
                fName = fullfile(app.directory, [partNames{c}, '.csv']);
                
                fid = fopen(fName, 'w');
                fprintf(fid, '%s\n', textHeader);
                fclose(fid);
                
                dlmwrite(fName, thisPart, '-append');
                
                c = c + 1;
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            app.SelectDirectoryButton = uibutton(app.UIFigure, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.directoryLabel = uilabel(app.UIFigure);
            app.directoryLabel.Text = './';
            app.NumberofImagesLabel = uilabel(app.UIFigure);
            app.NumberofImagesLabel.Text = 'Number of Images:';
            app.NumberOfImagesDisplay = uilabel(app.UIFigure);
            app.NumberOfImagesDisplay.Text = '0';
            app.forwardButton = uibutton(app.UIFigure, 'push');
            app.forwardButton.ButtonPushedFcn = createCallbackFcn(app, @forwardButtonPushed, true);
            app.forwardButton.Text = '>';
            app.backButton = uibutton(app.UIFigure, 'push');
            app.backButton.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.backButton.Text = '<';
            app.drawButton = uibutton(app.UIFigure, 'push');
            app.drawButton.ButtonPushedFcn = createCallbackFcn(app, @drawButtonPushed, true);
            app.drawButton.Text = 'Draw';
            app.PointsSpinnerLabel = uilabel(app.UIFigure);
            app.PointsSpinnerLabel.Text = 'Points';
            app.PointsSpinner = uispinner(app.UIFigure);
            app.PointsSpinner.Value = 10;
            app.RadiusSpinnerLabel = uilabel(app.UIFigure);
            app.RadiusSpinnerLabel.Text = 'Radius';
            app.RadiusSpinner = uispinner(app.UIFigure);
            app.RadiusSpinner.Value = 45;
            app.CurrentImageLabel = uilabel(app.UIFigure);
            app.CurrentImageLabel.Text = 'Current Image:';
            app.CurrentImageDisplay = uilabel(app.UIFigure);
            app.CurrentImageDisplay.Text = '0';
            app.MarkerPrefixEditFieldLabel = uilabel(app.UIFigure);
            app.MarkerPrefixEditFieldLabel.Text = 'Marker Prefix';
            app.MarkerPrefixEditField = uieditfield(app.UIFigure, 'text');
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'Save';
        end
    end

    methods (Access = public)

        % Construct app
        function app = WhiskerAnnotator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end